classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        Menu                         matlab.ui.container.Menu
        UIAxes                       matlab.ui.control.UIAxes
        UIAxes2                      matlab.ui.control.UIAxes
        TabGroup                     matlab.ui.container.TabGroup
        EfectosTab                   matlab.ui.container.Tab
        AadirefectoButton            matlab.ui.control.Button
        ListBox                      matlab.ui.control.ListBox
        InfoPanel                    matlab.ui.container.Panel
        CargaButton                  matlab.ui.control.Button
        GuardarButton                matlab.ui.control.Button
        PlayButton                   matlab.ui.control.Button
        PauseButton                  matlab.ui.control.Button
        StopButton                   matlab.ui.control.Button
        RecordButton                 matlab.ui.control.StateButton
        GenSenalesPanel              matlab.ui.container.Panel
        TipodeondaDropDownLabel      matlab.ui.control.Label
        TipoOnda                     matlab.ui.control.DropDown
        AmplitudLabel                matlab.ui.control.Label
        AmplitEditField              matlab.ui.control.EditField
        VLabel                       matlab.ui.control.Label
        HzLabel                      matlab.ui.control.Label
        FrecEditField                matlab.ui.control.EditField
        FrecuenciaLabel              matlab.ui.control.Label
        sLabel                       matlab.ui.control.Label
        DurationEditField            matlab.ui.control.EditField
        DuracionLabel                matlab.ui.control.Label
        GenerarButton                matlab.ui.control.Button
        HzLabel_2                    matlab.ui.control.Label
        FrecmuestreoEditField        matlab.ui.control.EditField
        FrecmuestreoLabel            matlab.ui.control.Label
        TextArea                     matlab.ui.control.TextArea
        ControldebufferPanel         matlab.ui.container.Panel
        Buffer                       matlab.ui.control.ListBox
        MostrarentiempoButton        matlab.ui.control.Button
        MostrarentiempoButton_2      matlab.ui.control.Button
        MostrarenfrecuenciaButton    matlab.ui.control.Button
        MostrarenfrecuenciaButton_2  matlab.ui.control.Button
        Engrafica1Label              matlab.ui.control.Label
        Engrafica2Label              matlab.ui.control.Label
    end

    
    properties (Access = public)
        last_data; %Datos de ultima modificación se escribira siempre que se aplique un efecto o se grabe algo por micro
        
        data; %Datos crudos de audio de lectura del fichero
        
        %variables auxiliares de control
        ngeneracion=0;
        
        %Variables para control de reproduccion
        recorder=audiorecorder(16000,16,2); %Objeto de grabacion por micro
        nrecord=0;
        
        pointer=0;%es posible que no haga falta pero por ahora lo dejo
        
        %Variables para control de reproduccion
        old_p=0; %Puesta a 0 para que no coincida nunca la primera vez que se da a play
        audio; %Objeto de audio para reproduccion
    end
    
    methods (Access = private)
        
        function mostrarInfo(app,fs,nmuestras,duracion,ncanales)
            app.TextArea.Value=sprintf( ...
                'Duración: %.2f s\n\nTasa de muestreo: %.2f Hz\n\nMuestras totales: %.0f\n\nNumero de canales: %.0f', ...
                string(duracion), ...
                string(fs), ...
                string(nmuestras), ...
                string(ncanales));
        end
        
        function dibPlotTiempos(app,base,datos)
            reset(app.UIAxes); %Limpamos grafica antes de dibujar
            plot(app.UIAxes,base,datos);
            xlabel(app.UIAxes,'Tiempo [s]');
            ylabel(app.UIAxes,'Amplitud [V]');
        end  
        
        function dibPlotFrecuencias(app,f,Y)
            reset(app.UIAxes2); %Limpamos grafica antes de dibujar
            plot(app.UIAxes2,f,Y);
            xlabel(app.UIAxes2,'Frecuencia [Hz]');
            ylabel(app.UIAxes2,'Amplitud [V]');
        end  
        
        %Funciones para la creacion de bases
        function [t,f]=createBase(~,fs,nmuestras)
            Ts=1/fs;
            t=0:Ts:(nmuestras*Ts-Ts);
            f=linspace(-fs/2,fs/2,nmuestras);
        end
        
        function position=getSelPosition(app) %asignamos el valor del apuntador a su posicion en la lista
            position=1;
            items=app.Buffer.Items;
            for i=1:length(items)
                if strcmp(app.Buffer.Value,items(i))
                    return;
                else
                    position=position+1;
                end
            end
            position=0;%devolvera 0 si no encuentra coincidencia
        end
        
        function includeData(app,signal,fs)
            %ODIO MATLAB POR QUE TENGO QUE HACER ESTO ASI Y NO LO PUEDE
            %HACER DIRECTAMENTE JODER UNA HORA PARA ESTA PUTA
            %CHORRADA ME SIENTO JODIDAMENTE IMBECIL JODER
            if(app.pointer==1)
                app.data=struct('signal',signal,'fs',fs);
            else
                app.data=[app.data struct('signal',signal,'fs',fs)];
            end
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            drawnow; 
            app.UIFigure.WindowState = 'maximized';
        end

        % Button pushed function: CargaButton
        function CargaButtonPushed(app, event)
            [file,path]=uigetfile({'*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a'...
                ,'Extensiones Audio (*.wav;*.flac;*.ogg;*.mp3;*.mp4;*.m4a)'},'Seleccione el archivo de audio'); 
            if isequal(file,0)
                return
            end
            app.pointer=app.pointer+1;
            
            [signal, fs_lectura]=audioread(fullfile(path,file));      %fullfile concatena el path y el archivo para poder leerlo desde donde sea
            
            includeData(app,signal,fs_lectura);
            
            %por alguna razon solo se muestra directamente si hacemos esto
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
            %preparamos muestra de info
            fs=app.data(app.pointer).fs;
            nmuestras=length(app.data(app.pointer).signal);
            duracion=nmuestras/fs;
            ncanales=size(app.data(app.pointer).signal,2);
            mostrarInfo(app,fs,nmuestras,duracion,ncanales);%función para mostrar informacion
            
            [t,f]=createBase(app,fs,nmuestras);
            TF=(1/length(app.data(app.pointer).signal)) * fftshift(fft(app.data(app.pointer).signal));
            dibPlotTiempos(app,t,app.data(app.pointer).signal);
            dibPlotFrecuencias(app,f,TF);
            
            info=audioinfo(fullfile(path,file));
            aux=split(string(info.Filename),'\');%lo pongo en aux para que no me entren ganas de vomitar cuando vea el codigo
            app.Buffer.Items=[app.Buffer.Items aux(end)];
        end

        % Button pushed function: PlayButton
        function PlayButtonPushed(app, event)
            position=getSelPosition(app);
            
            if position~=app.old_p && position>=1
                app.audio=audioplayer(app.data(position).signal,app.data(position).fs);
                app.old_p=position;
            end
            
            if (size(app.audio)~=0) 
                if strcmp(app.audio.Running,'off')%if anidado porque no puedo usar strmp hasta que me aseguro que audio no esta vacio
                    if app.audio.CurrentSample==1
                        play(app.audio);
                    else
                        resume(app.audio);
                    end
                end
            end
        end

        % Value changed function: RecordButton
        function RecordButtonValueChanged(app, event)
            value = app.RecordButton.Value;
            
            if value==1
                record(app.recorder);
            elseif value==0
                app.pointer=app.pointer+1;
                app.nrecord=app.nrecord+1;
                
                stop(app.recorder);
                grabacion=getaudiodata(app.recorder);
                
                includeData(app,grabacion,app.recorder.TotalSamples);
                
                [t,f]=createBase(app,app.recorder.SampleRate,app.recorder.TotalSamples);
                TF=(1/length(app.data(app.pointer).signal)) * fftshift(fft(app.data(app.pointer).signal));
                plot(app.UIAxes,t,app.data(app.pointer).signal);
                dibPlotFrecuencias(app,f,TF);
                
                fs=app.data(app.pointer).fs;
                nmuestras=length(app.data(app.pointer).signal);
                duracion=nmuestras/fs;
                ncanales=size(app.data(app.pointer).signal,2);
                mostrarInfo(app,fs,nmuestras,duracion,ncanales);
                
                app.Buffer.Items=[app.Buffer.Items strcat('grabacion ',int2str(app.nrecord))];
            end
        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
          if size(app.audio)~=0
              stop(app.audio);
          end
        end

        % Button pushed function: PauseButton
        function PauseButtonPushed(app, event)
          if size(app.audio)~=0
            if strcmp(app.audio.Running,'on')
                  pause(app.audio);
            end
          end
        end

        % Button pushed function: GuardarButton
        function GuardarButtonPushed(app, event)
            position=getSelPosition(app);
            
            if size(app.data(position).signal)~=0
                [file,path] = uiputfile({'*.wav';'*.mp4';'*.ogg';'*.flac';'*.m4a'},'Guarda la grabación','grabacion.wav'); 
                audiowrite(fullfile(path,file),app.data(position).signal,16000);%Hay que cambiar ese 16000 por algo que pueda elegir el usuario     
            end
            
            app.UIFigure.Visible = 'off';
            app.UIFigure.Visible = 'on';
            
        end

        % Button pushed function: GenerarButton
        function GenerarButtonPushed(app, event)
 % Generador de funciones elementales
            app.pointer=app.pointer+1;           
            app.ngeneracion=app.ngeneracion+1;
            % Parámetros introducidos por pantalla
            opcion=app.TipoOnda.Value;
            amplitud=str2double(app.AmplitEditField.Value);
            frecuencia=str2double(app.FrecEditField.Value);
            duracion=str2double(app.DurationEditField.Value);
            fs=str2double(app.FrecmuestreoEditField.Value);
            numMuestras=fs*duracion;
            
            [t,f]=createBase(app,fs,numMuestras);
            
            switch opcion
                case 'Seno'
                    y=amplitud * sin(2*pi*frecuencia * t);
                case 'Coseno'
                    y=amplitud * cos(2*pi*frecuencia * t);
                case 'Cuadrada'
                    y=amplitud * square(frecuencia * t);
                case 'Triangular'
                    y = amplitud * sawtooth(frecuencia * t, 0.5);
                case 'Sierra'
                    y = amplitud * sawtooth(frecuencia * t);    
            end
            
            Y = (1/length(y)) * fftshift(fft(y));
            
            dibPlotTiempos(app,t,y);
            dibPlotFrecuencias(app,f,Y);
            
            includeData(app,y,fs);
            
            aux=strcat('señal generada ',int2str(app.ngeneracion));
            app.Buffer.Items=[app.Buffer.Items strcat(aux,opcion)];
            mostrarInfo(app,fs,numMuestras,duracion,1);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.Menu = uimenu(app.UIFigure);
            app.Menu.Text = 'Menu';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Grafica 1')
            xlabel(app.UIAxes, 'Tiempo [s]')
            ylabel(app.UIAxes, 'Amplitud [V]')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Grafica 2')
            xlabel(app.UIAxes2, 'Frecuencia [Hz]')
            ylabel(app.UIAxes2, 'Amplitud [V]')
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EfectosTab = uitab(app.TabGroup);
            app.EfectosTab.Title = 'Efectos';
            app.AadirefectoButton = uibutton(app.EfectosTab, 'push');
            app.AadirefectoButton.Icon = 'add.png';
            app.AadirefectoButton.Text = 'Añadir efecto';
            app.ListBox = uilistbox(app.EfectosTab);
            app.InfoPanel = uipanel(app.UIFigure);
            app.InfoPanel.Title = 'Info';
            app.CargaButton = uibutton(app.UIFigure, 'push');
            app.CargaButton.ButtonPushedFcn = createCallbackFcn(app, @CargaButtonPushed, true);
            app.CargaButton.Icon = 'buscar.png';
            app.CargaButton.Text = 'Carga';
            app.GuardarButton = uibutton(app.UIFigure, 'push');
            app.GuardarButton.ButtonPushedFcn = createCallbackFcn(app, @GuardarButtonPushed, true);
            app.GuardarButton.Icon = 'guardar.png';
            app.GuardarButton.Text = 'Guardar';
            app.PlayButton = uibutton(app.UIFigure, 'push');
            app.PlayButton.ButtonPushedFcn = createCallbackFcn(app, @PlayButtonPushed, true);
            app.PlayButton.Icon = 'play.png';
            app.PlayButton.Text = '';
            app.PauseButton = uibutton(app.UIFigure, 'push');
            app.PauseButton.ButtonPushedFcn = createCallbackFcn(app, @PauseButtonPushed, true);
            app.PauseButton.Icon = 'pause.png';
            app.PauseButton.Text = '';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Icon = 'stop.png';
            app.StopButton.Text = '';
            app.RecordButton = uibutton(app.UIFigure, 'state');
            app.RecordButton.ValueChangedFcn = createCallbackFcn(app, @RecordButtonValueChanged, true);
            app.RecordButton.Icon = 'rec.png';
            app.RecordButton.Text = {''; ''};
            app.GenSenalesPanel = uipanel(app.UIFigure);
            app.GenSenalesPanel.Title = 'Generador de señales simples';
            app.TipodeondaDropDownLabel = uilabel(app.GenSenalesPanel);
            app.TipodeondaDropDownLabel.Text = 'Tipo de onda';
            app.TipoOnda = uidropdown(app.GenSenalesPanel);
            app.TipoOnda.Items = {'Elegir..', 'Seno', 'Coseno', 'Cuadrada', 'Triangular', 'Sierra'};
            app.TipoOnda.Value = 'Elegir..';
            app.AmplitudLabel = uilabel(app.GenSenalesPanel);
            app.AmplitudLabel.Text = 'Amplitud';
            app.AmplitEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.AmplitEditField.Value = '1';
            app.VLabel = uilabel(app.GenSenalesPanel);
            app.VLabel.Text = 'V';
            app.HzLabel = uilabel(app.GenSenalesPanel);
            app.HzLabel.Text = 'Hz';
            app.FrecEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecEditField.Value = '10';
            app.FrecuenciaLabel = uilabel(app.GenSenalesPanel);
            app.FrecuenciaLabel.Text = 'Frecuencia';
            app.sLabel = uilabel(app.GenSenalesPanel);
            app.sLabel.Text = 's';
            app.DurationEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.DurationEditField.Value = '2';
            app.DuracionLabel = uilabel(app.GenSenalesPanel);
            app.DuracionLabel.Text = 'Duracion';
            app.GenerarButton = uibutton(app.GenSenalesPanel, 'push');
            app.GenerarButton.ButtonPushedFcn = createCallbackFcn(app, @GenerarButtonPushed, true);
            app.GenerarButton.Text = 'Generar';
            app.HzLabel_2 = uilabel(app.GenSenalesPanel);
            app.HzLabel_2.Text = 'Hz';
            app.FrecmuestreoEditField = uieditfield(app.GenSenalesPanel, 'text');
            app.FrecmuestreoEditField.Value = '1000';
            app.FrecmuestreoLabel = uilabel(app.GenSenalesPanel);
            app.FrecmuestreoLabel.Text = 'Frec. muestreo';
            app.TextArea = uitextarea(app.UIFigure);
            app.ControldebufferPanel = uipanel(app.UIFigure);
            app.ControldebufferPanel.Title = 'Control de buffer';
            app.Buffer = uilistbox(app.ControldebufferPanel);
            app.Buffer.Items = {};
            app.Buffer.Value = {};
            app.MostrarentiempoButton = uibutton(app.ControldebufferPanel, 'push');
            app.MostrarentiempoButton.Text = 'Mostrar en tiempo';
            app.MostrarentiempoButton_2 = uibutton(app.ControldebufferPanel, 'push');
            app.MostrarentiempoButton_2.Text = 'Mostrar en tiempo';
            app.MostrarenfrecuenciaButton = uibutton(app.ControldebufferPanel, 'push');
            app.MostrarenfrecuenciaButton.Text = {'Mostrar en '; 'frecuencia'};
            app.MostrarenfrecuenciaButton_2 = uibutton(app.ControldebufferPanel, 'push');
            app.MostrarenfrecuenciaButton_2.Text = {'Mostrar en '; 'frecuencia'};
            app.Engrafica1Label = uilabel(app.ControldebufferPanel);
            app.Engrafica1Label.Text = 'En grafica 1 ...';
            app.Engrafica2Label = uilabel(app.ControldebufferPanel);
            app.Engrafica2Label.Text = 'En grafica 2 ...';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end